classdef laiiqautilities_win64 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ArchivoMenu                matlab.ui.container.Menu
        NuevoMenu                  matlab.ui.container.Menu
        CineticaOzonizacionMenu    matlab.ui.container.Menu
        VisorOzonogramaMenu        matlab.ui.container.Menu
        VisorEspectroUVVisMenu     matlab.ui.container.Menu
        BiorreaccionMenu           matlab.ui.container.Menu
        ReporteMenu                matlab.ui.container.Menu
        AbrirMenu                  matlab.ui.container.Menu
        OzonogramaMenu             matlab.ui.container.Menu
        EspectroUVVisMenu          matlab.ui.container.Menu
        BiorreaccionMenu2          matlab.ui.container.Menu
        GuardarMenu                matlab.ui.container.Menu
        GuardarcomoMenu            matlab.ui.container.Menu
        SalirMenu                  matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        CineticaOzonizacionTab     matlab.ui.container.Tab
        TabGroupCinetica           matlab.ui.container.TabGroup
        CineticaTab                matlab.ui.container.Tab
        MonitorLamp                matlab.ui.control.Lamp
        MonitorLabel               matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        ArduinoDropDownLabel       matlab.ui.control.Label
        ArduinoDropDown            matlab.ui.control.DropDown
        BuscarButton               matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        UnidadesTiempoDropDown     matlab.ui.control.DropDown
        TiempoEditFieldLabel       matlab.ui.control.Label
        TiempoEditField            matlab.ui.control.NumericEditField
        GananciaSpinnerLabel       matlab.ui.control.Label
        GananciaSpinner            matlab.ui.control.Spinner
        Panel_3                    matlab.ui.container.Panel
        DisplayEditField           matlab.ui.control.NumericEditField
        IniciarButton              matlab.ui.control.StateButton
        gNm3Label                  matlab.ui.control.Label
        Panel_4                    matlab.ui.container.Panel
        GuardarCineticaButton      matlab.ui.control.Button
        Panel_5                    matlab.ui.container.Panel
        ConsumidoLabel             matlab.ui.control.Label
        ResidualLabel              matlab.ui.control.Label
        TotalLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        gNm3CineticaDropDown       matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
        CineticaButton             matlab.ui.control.Button
        CrearnuevoLabel1           matlab.ui.control.Label
        HTML1                      matlab.ui.control.HTML
        OzonogramaTab              matlab.ui.container.Tab
        TabGroupOzonograma         matlab.ui.container.TabGroup
        OzonogramaTab2             matlab.ui.container.Tab
        MonitorLamp_2              matlab.ui.control.Lamp
        MonitorLabel_2             matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        GuardarOzonogramaButton    matlab.ui.control.Button
        Panel_7                    matlab.ui.container.Panel
        AbrirButton                matlab.ui.control.Button
        VersionButtonGroup         matlab.ui.container.ButtonGroup
        v2Button                   matlab.ui.control.RadioButton
        v3Button                   matlab.ui.control.RadioButton
        Panel_8                    matlab.ui.container.Panel
        ColordelineaDropDownLabel  matlab.ui.control.Label
        ColordelineaDropDown       matlab.ui.control.DropDown
        GridCheckBox               matlab.ui.control.CheckBox
        Panel_9                    matlab.ui.container.Panel
        ConsumidoLabel_2           matlab.ui.control.Label
        ResidualLabel_2            matlab.ui.control.Label
        TotalLabel_2               matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        gNm3OzonogramaDropDown     matlab.ui.control.DropDown
        TitleEditField             matlab.ui.control.EditField
        TitleEditField_2           matlab.ui.control.EditField
        ArchivoDropDown            matlab.ui.control.DropDown
        UIAxes2                    matlab.ui.control.UIAxes
        OzonogramaButton           matlab.ui.control.Button
        HTML2                      matlab.ui.control.HTML
        CrearnuevoLabel2           matlab.ui.control.Label
        EspectroUVTab              matlab.ui.container.Tab
        TabGroupEspectro           matlab.ui.container.TabGroup
        EspectroTab                matlab.ui.container.Tab
        EspectroButton             matlab.ui.control.Button
        HTML3                      matlab.ui.control.HTML
        CrearnuevoLabel3           matlab.ui.control.Label
        BiorreaccionTab            matlab.ui.container.Tab
        TabGroupBiorreaccion       matlab.ui.container.TabGroup
        BioreaccionTab             matlab.ui.container.Tab
        BiorreaccionButton         matlab.ui.control.Button
        HTML4                      matlab.ui.control.HTML
        CrearnuevoLabel4           matlab.ui.control.Label
        ReporteTab                 matlab.ui.container.Tab
        TabGroupReporte            matlab.ui.container.TabGroup
        ReporteTab5                matlab.ui.container.Tab
        ReporteButton              matlab.ui.control.Button
        HTML5                      matlab.ui.control.HTML
        CrearnuevoLabel5           matlab.ui.control.Label
        InstruccionesTab           matlab.ui.container.Tab
        HTML7                      matlab.ui.control.HTML
        HTML6                      matlab.ui.control.HTML
        AcercadeTab                matlab.ui.container.Tab
        HTML9                      matlab.ui.control.HTML
        HTML8                      matlab.ui.control.HTML
        HTML9_2                    matlab.ui.control.HTML
        GitHubButton               matlab.ui.control.Button
        HTML9_3                    matlab.ui.control.HTML
        Tab                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        t % Tiempo log Arduino
        oz % Concentración log Arduino
        
        a % Objeto Arduino
        ports % Puertos seriales encontrados
        
        flag
        datamatrix % Description
        f
        path
        
        residual1 % Ozono residual calculado cinetica
        consumido1 % Ozono consumido calculdo cinetica
        total1 % Total de ozono producido cinetica
        
        residual2 % Ozono residual calculado ozonograma
        consumido2 % Ozono consumido calculdo ozonograma
        total2 % Total de ozono producido ozonograma
        
        %         tab % Tabla para cargar archivos abiertos
        t_o % Tiempo log de archivo abierto
        oz_o % Concentración de archivo abierto
        tab
        versionfile % Versión de archivo para abrir
        ArchivoValue % Archivo seleccionado para graficar
        ozonetab % Description
    end
    
    methods (Access = public)
        
        %         function closeTab(app,src,event)
        %             delete(app.tg.SelectedTab)
        %         end


        function ArduinoData(app)
            %             if app.flag == 0
            app.DisplayEditField.Value = 0;
            app.flag = 0;
            try
                for i=1:app.TiempoEditField.Value*100
                    if app.IniciarButton.Value == 0 %|| isemty(app.a) == 1
                        app.IniciarButton.Enable = 'off';
                        app.IniciarButton.Text = 'Deteniendo...';
                        monitorSay(app,"#edb120",">> Deteniendo...","",3);
                        break;
                    end
                    app.t(i) = i/100;
                    app.oz(i) = app.a.analogRead(0)*(600/1023)*app.GananciaSpinner.Value;
                    app.DisplayEditField.Value = app.oz(i);
                    plot(app.UIAxes,app.t,app.oz);
                    pause(0.6);
                end
            catch
                app.IniciarButton.Enable = 'off';
                warning("Arduino desconectado, proceso interrumpido!");
                monitorSay(app,"red",">> Arduino desconectado, proceso interrumpido!","",3);
                app.IniciarButton.Text = 'Deteniendo...';
                app.IniciarButton.Enable = 'off';
                app.BuscarButton.Enable = "on";
                app.ArduinoDropDown.Enable = 'on';
                app.TiempoEditField.Enable = 'on';
                app.GuardarCineticaButton.Enable = 'on';
                app.UnidadesTiempoDropDown.Enable = 'on';
                app.IniciarButton.Text = 'Iniciar';
                clear app.a
                app.a = [];
                app.flag = 1;
                %                 monitorSay(app,"green",">> Listo!","",1.5);
            end
            try
                app.datamatrix = [app.t', round(app.oz'*100)/100];
                %                 disp(app.datamatrix)
            catch
                warning("Corrigiendo errores...");
                monitorSay(app,"#edb120",">> Corrigiendo errores...","",2);
                app.t(end) = [];
                app.datamatrix = [app.t', round(app.oz'*100)/100];
                %                 disp("Datos Obtenidos:")
                %                 disp(app.t')
                %                 disp(app.oz')
            end
            monitorSay(app,"#edb120","",">> Preparando datos, espere...",1);
            [app.residual1,app.consumido1,app.total1] = calculateOzone(app,app.t,app.oz);
            % app.tab = table(app.t, app.oz);
            % er1 = [app.t; app.oz];
            %             assignin('base', 'data', app.datamatrix)
            % assignin('base', 'data', app.data)
            % assignin('base', 'e1', er1)
            %             assignin('base', 'time', app.t)
            %             assignin('base', 'conc', app.oz)
            %             disp(app.data)
            % funcion para guardar archivos
            % saveDataFunc(app, app.t, app.oz);
            %             pause(1)
            %             app.IniciarButton.Text = 'Listo!';
            
            monitorSay(app,"green",">> Listo!","",2); % Monitor
            
            app.GuardarCineticaButton.Enable = 'on';
            if app.flag == 1
                app.IniciarButton.Enable = 'off';
            else
                app.IniciarButton.Enable = 'on';
            end
            app.IniciarButton.Value = 0;
            app.IniciarButton.Text = 'Iniciar';
            app.BuscarButton.Enable = "on";
            app.ArduinoDropDown.Enable = 'on';
            app.TiempoEditField.Enable = 'on';
            app.gNm3CineticaDropDown.Enable = "on";
            app.UnidadesTiempoDropDown.Enable = 'on';
            gNm3CineticaDropDownValueChanged(app);
            BuscarButtonPushed(app);
            pause(3)
        end


        % Función para el monitor en la app
        function monitorSay(app,color,message1,message2,time)
            app.MonitorLamp.Color = color;
            app.MonitorLabel.FontColor = color;
            app.MonitorLabel.Text = message1;
            pause(time)
            app.MonitorLabel.Text = message2;
            
        end


        % Función para el monitor en la app
        function monitorSay2(app,color,message1,message2,time)
            app.MonitorLamp_2.Color = color;
            app.MonitorLabel_2.FontColor = color;
            app.MonitorLabel_2.Text = message1;
            pause(time)
            app.MonitorLabel_2.Text = message2;
            
        end


        function [residual, consumido, total] = calculateOzone(app,time,conc)
            if isequal(time,0)
                residual = app.residual2;
                consumido = app.consumido2;
                total = app.total2;
            else
                residual = trapz(time,conc);
                consumido = max(time)*max(conc) - residual;
                total = residual + consumido;
                %                 disp(strcat("Consumido = " + consumido))
                %                 disp(strcat("Residual = " + residual))
                %                 disp(strcat("Total = " + total))
            end
        end


        function GuardarCinetica(app, time, conc, residual, consumido, total)
            datmatrix = [round(time'*100)/100, round(conc'*100)/100];
            app.f = 0;
            app.path = 0;
            % considerar la siguiente forma:
            [app.f,app.path,index] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel (*.xlsx)'; '*.csv', 'Archivo CSV (*.csv)'; '*.txt', 'Archivo de texto (*.txt)'}, 'Guardar como');
            if isequal(app.f,0) || isequal(app.path,0)
                %                 disp('User clicked Cancel.')
                monitorSay(app,"green",">> Ok!","",2);
                app.GuardarCineticaButton.Enable = 'on';
                monitorSay2(app,"green",">> Ok!","",2);
                app.GuardarOzonogramaButton.Enable = 'on';
            else
                if index == 1
                    data = [time;conc];
                    save(fullfile(app.path, app.f), 'data', 'consumido', 'residual', 'total', '-mat', '-v7.3');
                elseif index == 2
                    format short
                    xlswrite(fullfile(app.path,app.f), {'Ozono', app.gNm3CineticaDropDown.Value; 'Consumido: ', consumido; 'Residual: ', residual; 'Total: ', total}, 1, 'A1');
                    xlswrite(fullfile(app.path,app.f), {'Tiempo (min)', 'Concentracion (g/Nm^3)'}, 1, 'A6');
                    xlswrite(fullfile(app.path,app.f), datmatrix, 1, 'A7');
                    %                     xlswrite(fullfile(app.path,app.f), app.t', 1, 'A7');
                    %                     xlswrite(fullfile(app.path,app.f),round(app.oz'*100)/100,1,'B7');
                    %                     writematrix(app.datamatrix, fullfile(path,f), "WriteMode","append");
                elseif index == 3 || index == 4
                    fileID = fopen(fullfile(app.path,app.f), 'w');
                    fprintf(fileID,"Ozono, %s\n",app.gNm3CineticaDropDown.Value);
                    fprintf(fileID,"Consumido:, %.2f\n", consumido);
                    fprintf(fileID,"Residual:, %.2f\n", residual);
                    fprintf(fileID,"Total:, %.2f\n", total);
                    fprintf(fileID,"\n");
                    fprintf(fileID,"Tiempo (min), Concentracion (g/Nm^3)\n");
                    writematrix(datmatrix, fullfile(app.path, app.f), "WriteMode","append");
                    fclose(fileID);
                end
                app.GuardarCineticaButton.Enable = 'on';
                monitorSay(app,"green",">> Listo!","",2);
                app.GuardarOzonogramaButton.Enable = 'on';
                monitorSay2(app,"green",">> Listo!","",2);
            end
            % tomado de https://www.mathworks.com/help/matlab/ref/uiputfile.html
            % [filename, pathname, filterindex] = uiputfile( ...
            % {'*.m;*.fig;*.mat;*.slx;*.mdl',...
            %  'MATLAB Files (*.m,*.mlx,*.fig,*.mat,*.slx,*.mdl)';
            %  '*.m;*.mlx', 'program files (*.m,*.mlx)';...
            %  '*.fig','Figures (*.fig)';...
            %  '*.mat','MAT-files (*.mat)';...
            %  '*.slx;*.mdl','Models (*.slx,*.mdl)';...
            %  '*.*',  'All Files (*.*)'});
            % saveDataFunc(app, app.t, app.oz, app.area_sc, app.area_bc, app.area_t);
        end


        function GuardarOzonograma(app, datax, ozonetab)
            if isempty(app.ArchivoDropDown.Items) == 1
                monitorSay2(app,"green",">> Abre un archivo!","",2);
                app.GuardarOzonogramaButton.Enable = 'off';
                app.AbrirButton.Enable = "on";
                app.ArchivoDropDown.Enable = "on";
            elseif app.ArchivoValue == "Todos"
                [app.f,app.path,index] = uiputfile({'*.xlsx', 'Excel (*.xlsx)'; '*.csv', 'Archivo CSV (*.csv)'; '*.txt', 'Archivo de texto (*.txt)'}, 'Guardar como');
                if isequal(app.f,0) || isequal(app.path,0)
                    monitorSay2(app,"green",">> Ok!","",2);
                    app.GuardarOzonogramaButton.Enable = 'on';
                    app.AbrirButton.Enable = "on";
                    app.ArchivoDropDown.Enable = "on";
                    app.gNm3OzonogramaDropDown.Enable = "on";
                else
                    for i=1:width(datax)
                        maximum(i) = length(datax.(i));
                    end
                    [~,idx] = max(maximum);
                    time = 1:length(datax.(idx));
                    if app.versionfile.Text == "v2"
                        time = time/6000;
                    end
                    %                         assignin("base","time",time);
                    time = round(time*100)/100;
                    if index == 1
                        format short
                        % assignin("base","ozonetab",ozonetab);
                        xlswrite(fullfile(app.path,app.f), {'Ozono';"Consumido:";"Residual:";"Total:"}, 1, "A1");
                        xlswrite(fullfile(app.path,app.f), {app.gNm3OzonogramaDropDown.Value}, 1, "B1");
                        xlswrite(fullfile(app.path,app.f), {'Tiempo (min)', 'Concentracion (g/Nm^3)'}, 1, "A6");
                        xlswrite(fullfile(app.path,app.f), time', 1, "A7");
                        for i=1:width(datax)
                            xlswrite(fullfile(app.path,app.f), ozonetab.(i), 1, strcat(char(i+1+64) + "2"));
                            xlswrite(fullfile(app.path,app.f), datax.(i)', 1, strcat(char(i+1+64) + "7"));
                        end
                    elseif index == 2 || index == 3
                        fileID = fopen(fullfile(app.path,app.f), 'w');
                        fprintf(fileID,"Ozono, %s\n",app.gNm3CineticaDropDown.Value);
                        fprintf(fileID,"Consumido:,");
                        for i=1:width(datax)
                            fprintf(fileID,"%.2f, ",ozonetab.(i)(1,1));
                        end
                        fprintf(fileID,"\n");
                        fprintf(fileID,"Residual:,");
                        for i=1:width(datax)
                            fprintf(fileID,"%.2f, ", ozonetab.(i)(2,1));
                        end
                        fprintf(fileID,"\n");
                        fprintf(fileID,"Total:,");
                        for i=1:width(datax)
                            fprintf(fileID, "%.2f, ",ozonetab.(i)(3,1));
                        end
                        fprintf(fileID,"\n");
                        fprintf(fileID,"\n");
                        fprintf(fileID,"Tiempo (min), Concentracion (g/Nm^3)\n");
                        for i=1:length(datax.(i))
                            fprintf(fileID,"%d, ",time(i));
                            for j=1:width(datax)
                                try
                                    fprintf(fileID,"%.2f",datax.(j)(i));
                                catch
                                    datax.(j)(i) = NaN;
                                    fprintf(fileID,"%.2f",datax.(j)(i));
                                end
                                fprintf(fileID,", ");
                            end
                            fprintf(fileID,"\n");
                        end
                        % writematrix([time' data], fullfile(app.path, app.f), "WriteMode","append","FileType","text");
                        fclose(fileID);
                    end
                end
                app.GuardarOzonogramaButton.Enable = 'on';
                app.AbrirButton.Enable = "on";
                app.ArchivoDropDown.Enable = "on";
                app.gNm3OzonogramaDropDown.Enable = "on";
                monitorSay2(app,"green",">> Listo!","",2);
            else
                [app.f,app.path,index] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel (*.xlsx)'; '*.csv', 'Archivo CSV (*.csv)'; '*.txt', 'Archivo de texto (*.txt)'}, 'Guardar como');
                if isequal(app.f,0) || isequal(app.path,0)
                    monitorSay2(app,"green",">> Ok!","",2);
                    app.GuardarOzonogramaButton.Enable = 'on';
                    app.AbrirButton.Enable = "on";
                    app.ArchivoDropDown.Enable = "on";
                    app.gNm3OzonogramaDropDown.Enable = "on";
                else
                    format short
                    consumido = ozonetab.(app.ArchivoDropDown.Value)(1);
                    residual = ozonetab.(app.ArchivoDropDown.Value)(2);
                    total = ozonetab.(app.ArchivoDropDown.Value)(3);
                    time = 1:length(datax.(app.ArchivoDropDown.Value));
                    if app.versionfile.Text == "v2"
                        time = time/6000;
                    end
                    time = round(time*100)/100;
                    if index == 1
                        data = [time;datax.(app.ArchivoDropDown.Value)];
                        save(fullfile(app.path, app.f), 'data', 'consumido', 'residual', 'total', '-mat', '-v7.3');
                    elseif index == 2
                        %                         format short
                        xlswrite(fullfile(app.path,app.f), {'Ozono', app.gNm3CineticaDropDown.Value; 'Consumido: ', consumido; 'Residual: ', residual; 'Total: ', total}, 1, "A1");
                        xlswrite(fullfile(app.path,app.f), {'Tiempo (min)', 'Concentracion (g/Nm^3)'}, 1, "A6");
                        xlswrite(fullfile(app.path,app.f), time', 1, "A7");
                        xlswrite(fullfile(app.path,app.f), datax.(app.ArchivoDropDown.Value)', 1, "B7");
                        %                     xlswrite(fullfile(app.path,app.f), app.t', 1, 'A7');
                        %                     xlswrite(fullfile(app.path,app.f),round(app.oz'*100)/100,1,'B7');
                        %                     writematrix(app.datamatrix, fullfile(path,f), "WriteMode","append");
                    elseif index == 3 || index == 4
                        fileID = fopen(fullfile(app.path,app.f), 'w');
                        fprintf(fileID,"Ozono, %s\n",app.gNm3CineticaDropDown.Value);
                        fprintf(fileID,"Consumido:, %.2f\n", consumido);
                        fprintf(fileID,"Residual:, %.2f\n", residual);
                        fprintf(fileID,"Total:, %.2f\n", total);
                        fprintf(fileID,"\n");
                        fprintf(fileID,"Tiempo (min), Concentracion (g/Nm^3)\n");
                        writematrix(datax.(app.ArchivoDropDown.Value)', fullfile(app.path, app.f), "WriteMode","append");
                        fclose(fileID);
                    end
                end
                app.GuardarOzonogramaButton.Enable = 'on';
                app.AbrirButton.Enable = "on";
                app.ArchivoDropDown.Enable = "on";
                app.gNm3OzonogramaDropDown.Enable = "on";
                monitorSay2(app,"green",">> Listo!","",2);
            end
        end


        function [tab] = AbrirArchivo(app)
            app.f = 0;
            app.path = 0;
            [app.f, app.path] = uigetfile({'*.mat'},'Seleccionar Archivo', 'MultiSelect','on');
            if isequal(app.f,0)
                monitorSay2(app,"green",">> Ok!","",2);
                tab = app.tab;
            else
                tab = table();
                if length(string(app.f)) == 1
                    varlist = who('-file', fullfile(app.path, app.f));
                    dt = importdata(fullfile(app.path, app.f));
                    if length(varlist) > 1
                        tab.(app.f) = round(dt.('data')(2,:)*100)/100;
                    else
                        tab.(app.f) = round(dt(2,:)*100)/100;
                    end
                    app.ArchivoDropDown.Items = [string(app.f)];
                    
                elseif length(string(app.f)) > 1
                    app.ArchivoDropDown.Items = ["Todos"];
                    for i=1:length(string(app.f))
                        varlist = who('-file', fullfile(app.path, app.f{i}));
                        dt = importdata(fullfile(app.path, app.f{i}));
                        if length(varlist) > 1
                            tab.(app.f{i}) = round(dt.('data')(2,:)*100)/100;
                        else
                            tab.(app.f{i}) = round(dt(2,:)*100)/100;
                        end
                        app.ArchivoDropDown.Items = [app.ArchivoDropDown.Items app.f{i}];
                    end
                end
                % plotArchivos(app,tab);
                % monitorSay2(app,"green",">> Listo!","",2);
                monitorSay2(app,"green",">> Listo!","",2);
            end
        end


        function plotArchivos(app, data)
            app.residual2 = 0;
            app.consumido2 = 0;
            app.total2 = 0;
            app.UIAxes2.YLim = [0 40];
            app.ozonetab = table();
            plot(app.UIAxes2,0);
            if isequal(data,0)
                % Nada
            else
                if  app.ArchivoValue == "Todos"
                    hold(app.UIAxes2, 'on')
                    for i=1:width(data)
                        time = 1:length(data.(i));
                        if app.versionfile.Text == "v2"
                            time = time/6000;
                        end
                        if app.versionfile.Text == "v3"
                            time = time/100;
                        end
                        %                     assignin("base","time",time);
                        plot(app.UIAxes2,time,data.(i));
                        [res,cons,tot] = calculateOzone(app,time,data.(i));
                        app.residual2 = app.residual2 + res;
                        app.consumido2 = app.consumido2 + cons;
                        app.total2 = app.total2 + tot;
                        app.ozonetab.(i) = [cons;res;tot];
                    end
                    hold(app.UIAxes2, 'off');
                else
                    time = 1:length(data.(app.ArchivoValue));
                    if app.versionfile.Text == "v2"
                        time = time/6000;
                    end
                    if app.versionfile.Text == "v3"
                        time = time/100;
                    end
                    % app.UIAxes2.XLim = [0 max(x)];
                    %                 assignin("base","time",time);
                    plot(app.UIAxes2,time,data.(app.ArchivoValue));
                    [app.residual2,app.consumido2,app.total2] = calculateOzone(app,time,data.(app.ArchivoValue));
                    app.ozonetab.(app.ArchivoDropDown.Value) = [app.consumido2,app.residual2,app.total2];
                end
                %                 assignin("base","ozono",app.ozonetab);
                %                 assignin("base","data",app.tab);
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % Visibilidad de TabsGroups apagada
            app.TabGroupCinetica.Visible = 'off';
            app.TabGroupOzonograma.Visible = "off";
            app.TabGroupEspectro.Visible = "off";
            app.TabGroupBiorreaccion.Visible = "off";
            app.TabGroupReporte.Visible = "off";
            app.residual2 = 0;
            app.consumido2 = 0;
            app.total2 = 0;
            app.tab = 0;
        end

        % Button pushed function: CineticaButton
        function CineticaButtonPushed(app, event)
            app.HTML1.Visible = 'off';
            app.CrearnuevoLabel1.Visible = 'off';
            app.CineticaButton.Visible = 'off';
            
            app.TabGroupCinetica.Visible = 'on';
            app.TabGroupCinetica.Position = app.TabGroup.Position;
            
            monitorSay(app,"red","","",0);
            
            app.IniciarButton.Enable = "off";
            app.GuardarCineticaButton.Enable = "off";
            app.gNm3CineticaDropDown.Enable = "off";
            
            % app.UIAxes.XLabel.Color = 'green';
            app.UIAxes.YLim = [0 40];
            app.UIAxes.XLim = [0 app.TiempoEditField.Value];
            app.UIAxes.XColor = 'green';
            app.UIAxes.YColor = 'g';
            app.UIAxes.XLabel.Interpreter = 'tex';
            app.UIAxes.YLabel.Interpreter = 'tex';
            app.UIAxes.XLabel.String = 'Tiempo (min)';
            app.UIAxes.YLabel.String = "Concentración [g/Nm^{3}]";
            
            app.gNm3Label.Interpreter = 'latex';
            app.gNm3Label.FontSize = 16;
            
            app.Label.Text = "------";
            app.Label_2.Text = "------";
            app.Label_3.Text = "------";
            
            UnidadesTiempoDropDownValueChanged(app)
        end

        % Button pushed function: OzonogramaButton
        function OzonogramaButtonPushed(app, event)
            app.HTML2.Visible = 'off';
            app.CrearnuevoLabel2.Visible = 'off';
            app.OzonogramaButton.Visible = 'off';
            
            app.TabGroupOzonograma.Visible = 'on';
            app.TabGroupOzonograma.Position = app.TabGroup.Position;
            
            monitorSay2(app,"green","","",0);
            
            app.UIAxes2.XGrid = "on";
            app.UIAxes2.YGrid = "on";
            app.UIAxes2.YLim = [0 40];
            %             app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XColor = "green";
            app.UIAxes2.YColor = "green";
            app.UIAxes2.XLabel.Interpreter = 'tex';
            app.UIAxes2.YLabel.Interpreter = 'tex';
            app.UIAxes2.XLabel.String = 'Tiempo (min)';
            app.UIAxes2.YLabel.String = "Concentración [g/Nm^{3}]";
            
            app.gNm3OzonogramaDropDown.Enable = "off";
            app.Label_4.Text = "------";
            app.Label_5.Text = "------";
            app.Label_6.Text = "------";
            
            app.GuardarOzonogramaButton.Enable = "off";
        end

        % Button pushed function: EspectroButton
        function EspectroButtonPushed(app, event)
            app.HTML3.Visible = 'off';
            app.CrearnuevoLabel3.Visible = 'off';
            app.EspectroButton.Visible = 'off';
            
            app.TabGroupEspectro.Visible = 'on';
            app.TabGroupEspectro.Position = app.TabGroup.Position;
        end

        % Button pushed function: BiorreaccionButton
        function BiorreaccionButtonPushed(app, event)
            app.HTML4.Visible = 'off';
            app.CrearnuevoLabel4.Visible = 'off';
            app.BiorreaccionButton.Visible = 'off';
            
            app.TabGroupBiorreaccion.Visible = 'on';
            app.TabGroupBiorreaccion.Position = app.TabGroup.Position;
        end

        % Button pushed function: ReporteButton
        function ReporteButtonPushed(app, event)
            app.HTML5.Visible = 'off';
            app.CrearnuevoLabel5.Visible = 'off';
            app.ReporteButton.Visible = 'off';
            
            app.TabGroupReporte.Visible = 'on';
            app.TabGroupReporte.Position = app.TabGroup.Position;
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            app.ports = {};
            app.ArduinoDropDown.Items = {};
            app.ports = char(serialportlist);
            %             disp("Puertos: ")
            %             disp(app.ports)
            if isempty(app.ports)
                app.ArduinoDropDown.Items = [app.ArduinoDropDown.Items];
                monitorSay(app,"red",">> No se detectaron puertos serie","",1.5);
                app.IniciarButton.Enable = "off";
                clear app.a
                app.a = [];
            else
                app.ArduinoDropDown.Items = { app.ports};
                monitorSay(app,"green",">> Listo!","",2);
                app.IniciarButton.Enable = "on";
                %                 disp("Items: ")
                %                 disp(app.ArduinoDropDown.Items)
            end
        end

        % Value changed function: UnidadesTiempoDropDown
        function UnidadesTiempoDropDownValueChanged(app, event)
            %             value = app.UnidadesTiempoDropDown.Value;
            %             if value == "min"
            %                 app.UIAxes.XLabel.String = "Tiempo (min)";
            %             end
            %             if value == "hr"
            %                 app.UIAxes.XLabel.String = "Tiempo (hr)";
            %             end
            %             if value == "seg"
            %                 app.UIAxes.XLabel.String = "Tiempo (seg)";
            %             end
        end

        % Value changed function: TiempoEditField
        function TiempoEditFieldValueChanged(app, event)
            app.UIAxes.XLim = [0 app.TiempoEditField.Value];
            UnidadesTiempoDropDownValueChanged(app)
        end

        % Value changed function: IniciarButton
        function IniciarButtonValueChanged(app, event)
            value = app.IniciarButton.Value;
            app.ports = char(serialportlist);
            app.Label.Text = "-------";
            app.Label_2.Text = "-------";
            app.Label_3.Text = "-------";
            app.gNm3CineticaDropDown.Enable = "off";
            if value == 1 && isempty(app.ports) == 1
                monitorSay(app,"red",">> No se detectaron puertos serie.","",2);
                app.IniciarButton.Value = 0;
                app.IniciarButton.Enable = "off";
                app.GuardarCineticaButton.Enable = "off";
                clear app.a;
                app.a = [];
            elseif value == 1 && isempty(app.ports) == 0
                %                 app.IniciarButton.Enable = "off";
                app.BuscarButton.Enable = "off";
                app.GuardarCineticaButton.Enable = "off";
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.UnidadesTiempoDropDown.Enable = 'off';
                monitorSay(app,"#edb120","",strcat(">> Conectando Arduino en " + app.ArduinoDropDown.Value + ", espere..."),0);
                if isempty(app.a) == 1
                    app.a = arduino(app.ArduinoDropDown.Value);
                else
                    app.a = app.a;
                    flush(app.a);
                end
                monitorSay(app,"green",">> Listo!","",1);
                monitorSay(app,"#edb120","",">> En progreso...",0);
                app.IniciarButton.Text = "Detener";
                clear app.t app.oz;
                app.t = 0;
                app.oz = 0;
                app.datamatrix = [];
                % app.UIAxes.XLim = [0 app.TiempoEditField.Value];
                plot(app.UIAxes,app.oz);
                flush(app.a)
                % app.UIAxes.XLim = [0 app.TiempoEditField.Value];
                % for i=1:length(app.UIAxes.XTick)
                % app.UIAxes.XTickLabel{i} = num2str(round(app.UIAxes.XTick(i)/60));
                % app.UIAxes.XTickLabel{i} = num2str(app.UIAxes.XTick(i)/60,"%.1f");
                % end
                ArduinoData(app);
            end
        end

        % Button pushed function: GuardarCineticaButton
        function GuardarCineticaButtonPushed(app, event)
            app.GuardarCineticaButton.Enable = 'off';
            monitorSay(app,"#edb120","",">> Guardando...",0);
            [app.residual1,app.consumido1,app.total1] = calculateOzone(app,app.t,app.oz);
            GuardarCinetica(app, app.t,app.oz,app.residual1,app.consumido1,app.total1);
        end

        % Value changed function: gNm3CineticaDropDown
        function gNm3CineticaDropDownValueChanged(app, event)
            value = app.gNm3CineticaDropDown.Value;
            if value == "g/Nm3"
                app.Label.Text = num2str(app.consumido1,"%.2f");
                app.Label_2.Text = num2str(app.residual1,"%.2f");
                app.Label_3.Text = num2str(app.total1,"%.2f");
            end
            if value == "g/L"
                residual = app.residual1/1000;
                consumido = app.consumido1/1000;
                total = app.total1/1000;
                app.Label.Text = num2str(consumido,"%.3f");
                app.Label_2.Text = num2str(residual,"%.3f");
                app.Label_3.Text = num2str(total,"%.3f");
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.AbrirButton.Enable = "off";
            app.gNm3OzonogramaDropDown.Enable = "off";
            app.GuardarOzonogramaButton.Enable = "off";
            
            monitorSay2(app,"#edb120","",">> Cargando archivos...",0);
            
            VersionButtonGroupSelectionChanged(app);
            [app.tab] = AbrirArchivo(app);
            if isequal(app.tab,0)
                % Nada
            else
                ArchivoDropDownValueChanged(app);
            end
            
            % plotArchivos(app,app.tab);
            
            % gNm3OzonogramaDropDownValueChanged(app);
            
            % monitorSay2(app,"green",">> Listo!","",2);
            
            app.AbrirButton.Enable = "on";
            app.gNm3OzonogramaDropDown.Enable = "on";
            app.GuardarOzonogramaButton.Enable = "on";
        end

        % Value changed function: gNm3OzonogramaDropDown
        function gNm3OzonogramaDropDownValueChanged(app, event)
            value = app.gNm3OzonogramaDropDown.Value;
            if value == "g/Nm3"
                app.Label_4.Text = num2str(app.consumido2,"%.2f");
                app.Label_5.Text = num2str(app.residual2,"%.2f");
                app.Label_6.Text = num2str(app.total2,"%.2f");
            end
            if value == "g/L"
                residual = app.residual2/1000;
                consumido = app.consumido2/1000;
                total = app.total2/1000;
                app.Label_4.Text = num2str(consumido,"%.3f");
                app.Label_5.Text = num2str(residual,"%.3f");
                app.Label_6.Text = num2str(total,"%.3f");
            end
        end

        % Button pushed function: GuardarOzonogramaButton
        function GuardarOzonogramaButtonButtonPushed(app, event)
            app.GuardarOzonogramaButton.Enable = 'off';
            app.AbrirButton.Enable = "off";
            app.gNm3OzonogramaDropDown.Enable = "off";
            app.ArchivoDropDown.Enable = "off";
            monitorSay2(app,"#edb120","",">> Guardando...",0);
            VersionButtonGroupSelectionChanged(app);
            GuardarOzonograma(app,app.tab, app.ozonetab);
        end

        % Value changed function: ArchivoDropDown
        function ArchivoDropDownValueChanged(app, event)
            app.ArchivoValue = app.ArchivoDropDown.Value;
            if app.ArchivoValue == "Todos"
                app.ColordelineaDropDown.Enable = "off";
            else
                app.ColordelineaDropDown.Enable = "on";
            end
            plotArchivos(app,app.tab);
            gNm3OzonogramaDropDownValueChanged(app);
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value == 1
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
            end
            if value == 0
                app.UIAxes2.XGrid = "off";
                app.UIAxes2.YGrid = "off";
            end
        end

        % Selection changed function: VersionButtonGroup
        function VersionButtonGroupSelectionChanged(app, event)
            app.versionfile = app.VersionButtonGroup.SelectedObject;
        end

        % Button pushed function: GitHubButton
        function GitHubButtonPushed(app, event)
            web("https://github.com/TheBiotechScientist/LAIIQAUtilities/tree/v3.0.0-alpha");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevoMenu = uimenu(app.ArchivoMenu);
            app.NuevoMenu.Text = 'Nuevo';
            app.CineticaOzonizacionMenu = uimenu(app.NuevoMenu);
            app.CineticaOzonizacionMenu.Text = 'Cinética Ozonización';
            app.VisorOzonogramaMenu = uimenu(app.NuevoMenu);
            app.VisorOzonogramaMenu.Text = 'Visor Ozonograma';
            app.VisorEspectroUVVisMenu = uimenu(app.NuevoMenu);
            app.VisorEspectroUVVisMenu.Text = 'Visor EspectroUV-Vis';
            app.BiorreaccionMenu = uimenu(app.NuevoMenu);
            app.BiorreaccionMenu.Text = 'Biorreacción';
            app.ReporteMenu = uimenu(app.NuevoMenu);
            app.ReporteMenu.Text = 'Reporte';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.Text = 'Abrir';
            app.OzonogramaMenu = uimenu(app.AbrirMenu);
            app.OzonogramaMenu.Text = 'Ozonograma';
            app.EspectroUVVisMenu = uimenu(app.AbrirMenu);
            app.EspectroUVVisMenu.Text = 'EspectroUV-Vis';
            app.BiorreaccionMenu2 = uimenu(app.AbrirMenu);
            app.BiorreaccionMenu2.Text = 'Biorreacción';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.Text = 'Guardar';
            app.GuardarcomoMenu = uimenu(app.ArchivoMenu);
            app.GuardarcomoMenu.Text = 'Guardar como';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Text = 'Salir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CineticaOzonizacionTab = uitab(app.TabGroup);
            app.CineticaOzonizacionTab.Title = 'Cinética Ozonización';
            app.TabGroupCinetica = uitabgroup(app.CineticaOzonizacionTab);
            app.TabGroupCinetica.Visible = 'off';
            app.CineticaTab = uitab(app.TabGroupCinetica);
            app.CineticaTab.Title = 'Cinética';
            app.MonitorLamp = uilamp(app.CineticaTab);
            app.MonitorLabel = uilabel(app.CineticaTab);
            app.MonitorLabel.Text = '>> Monitor Label';
            app.Panel = uipanel(app.CineticaTab);
            app.ArduinoDropDownLabel = uilabel(app.Panel);
            app.ArduinoDropDownLabel.Text = 'Arduino';
            app.ArduinoDropDown = uidropdown(app.Panel);
            app.ArduinoDropDown.Items = {};
            app.ArduinoDropDown.Value = {};
            app.BuscarButton = uibutton(app.Panel, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.Panel_2 = uipanel(app.CineticaTab);
            app.UnidadesTiempoDropDown = uidropdown(app.Panel_2);
            app.UnidadesTiempoDropDown.Items = {'min'};
            app.UnidadesTiempoDropDown.ValueChangedFcn = createCallbackFcn(app, @UnidadesTiempoDropDownValueChanged, true);
            app.UnidadesTiempoDropDown.Value = 'min';
            app.TiempoEditFieldLabel = uilabel(app.Panel_2);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.Panel_2, 'numeric');
            app.TiempoEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoEditFieldValueChanged, true);
            app.TiempoEditField.Value = 60;
            app.GananciaSpinnerLabel = uilabel(app.Panel_2);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.GananciaSpinner = uispinner(app.Panel_2);
            app.GananciaSpinner.Step = 0.0001;
            app.GananciaSpinner.ValueDisplayFormat = '%.4f';
            app.GananciaSpinner.Value = 0.1674;
            app.Panel_3 = uipanel(app.CineticaTab);
            app.DisplayEditField = uieditfield(app.Panel_3, 'numeric');
            app.DisplayEditField.Editable = 'off';
            app.IniciarButton = uibutton(app.Panel_3, 'state');
            app.IniciarButton.ValueChangedFcn = createCallbackFcn(app, @IniciarButtonValueChanged, true);
            app.IniciarButton.Text = 'Iniciar';
            app.gNm3Label = uilabel(app.Panel_3);
            app.gNm3Label.Text = '$g/Nm^{3}$';
            app.Panel_4 = uipanel(app.CineticaTab);
            app.GuardarCineticaButton = uibutton(app.Panel_4, 'push');
            app.GuardarCineticaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarCineticaButtonPushed, true);
            app.GuardarCineticaButton.Text = 'Guardar';
            app.Panel_5 = uipanel(app.CineticaTab);
            app.ConsumidoLabel = uilabel(app.Panel_5);
            app.ConsumidoLabel.Text = 'Consumido:';
            app.ResidualLabel = uilabel(app.Panel_5);
            app.ResidualLabel.Text = 'Residual:';
            app.TotalLabel = uilabel(app.Panel_5);
            app.TotalLabel.Text = 'Total:';
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '1252.34';
            app.Label_2 = uilabel(app.Panel_5);
            app.Label_2.Text = '1252.34';
            app.Label_3 = uilabel(app.Panel_5);
            app.Label_3.Text = '1252.34';
            app.gNm3CineticaDropDown = uidropdown(app.Panel_5);
            app.gNm3CineticaDropDown.Items = {'g/Nm3', 'g/L'};
            app.gNm3CineticaDropDown.ValueChangedFcn = createCallbackFcn(app, @gNm3CineticaDropDownValueChanged, true);
            app.gNm3CineticaDropDown.Value = 'g/Nm3';
            app.UIAxes = uiaxes(app.CineticaTab);
            app.CineticaButton = uibutton(app.CineticaOzonizacionTab, 'push');
            app.CineticaButton.ButtonPushedFcn = createCallbackFcn(app, @CineticaButtonPushed, true);
            app.CineticaButton.Text = 'Cinética';
            app.CrearnuevoLabel1 = uilabel(app.CineticaOzonizacionTab);
            app.CrearnuevoLabel1.Text = 'Crear nuevo:';
            app.HTML1 = uihtml(app.CineticaOzonizacionTab);
            app.HTML1.HTMLSource = 'html/logo.html';
            app.OzonogramaTab = uitab(app.TabGroup);
            app.OzonogramaTab.Title = 'Ozonograma';
            app.TabGroupOzonograma = uitabgroup(app.OzonogramaTab);
            app.TabGroupOzonograma.Visible = 'off';
            app.OzonogramaTab2 = uitab(app.TabGroupOzonograma);
            app.OzonogramaTab2.Title = 'Ozonograma';
            app.MonitorLamp_2 = uilamp(app.OzonogramaTab2);
            app.MonitorLabel_2 = uilabel(app.OzonogramaTab2);
            app.MonitorLabel_2.Text = '>> Monitor Label';
            app.Panel_6 = uipanel(app.OzonogramaTab2);
            app.GuardarOzonogramaButton = uibutton(app.Panel_6, 'push');
            app.GuardarOzonogramaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarOzonogramaButtonButtonPushed, true);
            app.GuardarOzonogramaButton.Text = 'Guardar';
            app.Panel_7 = uipanel(app.OzonogramaTab2);
            app.AbrirButton = uibutton(app.Panel_7, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.VersionButtonGroup = uibuttongroup(app.Panel_7);
            app.VersionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VersionButtonGroupSelectionChanged, true);
            app.v2Button = uiradiobutton(app.VersionButtonGroup);
            app.v2Button.Text = 'v2';
            app.v2Button.Value = true;
            app.v3Button = uiradiobutton(app.VersionButtonGroup);
            app.v3Button.Text = 'v3';
            app.Panel_8 = uipanel(app.OzonogramaTab2);
            app.ColordelineaDropDownLabel = uilabel(app.Panel_8);
            app.ColordelineaDropDownLabel.Text = 'Color de linea';
            app.ColordelineaDropDown = uidropdown(app.Panel_8);
            app.ColordelineaDropDown.Items = {'azul', 'naranja', 'amarillo', 'morado', 'verde', 'cian', 'rojo'};
            app.ColordelineaDropDown.Value = 'azul';
            app.GridCheckBox = uicheckbox(app.Panel_8);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox.Value = true;
            app.Panel_9 = uipanel(app.OzonogramaTab2);
            app.ConsumidoLabel_2 = uilabel(app.Panel_9);
            app.ConsumidoLabel_2.Text = 'Consumido:';
            app.ResidualLabel_2 = uilabel(app.Panel_9);
            app.ResidualLabel_2.Text = 'Residual:';
            app.TotalLabel_2 = uilabel(app.Panel_9);
            app.TotalLabel_2.Text = 'Total:';
            app.Label_4 = uilabel(app.Panel_9);
            app.Label_4.Text = '1252.34';
            app.Label_5 = uilabel(app.Panel_9);
            app.Label_5.Text = '1252.34';
            app.Label_6 = uilabel(app.Panel_9);
            app.Label_6.Text = '1252.34';
            app.gNm3OzonogramaDropDown = uidropdown(app.Panel_9);
            app.gNm3OzonogramaDropDown.Items = {'g/Nm3', 'g/L'};
            app.gNm3OzonogramaDropDown.ValueChangedFcn = createCallbackFcn(app, @gNm3OzonogramaDropDownValueChanged, true);
            app.gNm3OzonogramaDropDown.Value = 'g/Nm3';
            app.TitleEditField = uieditfield(app.OzonogramaTab2, 'text');
            app.TitleEditField.Value = 'Titulo (opcional)';
            app.TitleEditField_2 = uieditfield(app.OzonogramaTab2, 'text');
            app.TitleEditField_2.Value = 'Subtitulo (opcional)';
            app.ArchivoDropDown = uidropdown(app.OzonogramaTab2);
            app.ArchivoDropDown.Items = {};
            app.ArchivoDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchivoDropDownValueChanged, true);
            app.ArchivoDropDown.Value = {};
            app.UIAxes2 = uiaxes(app.OzonogramaTab2);
            app.OzonogramaButton = uibutton(app.OzonogramaTab, 'push');
            app.OzonogramaButton.ButtonPushedFcn = createCallbackFcn(app, @OzonogramaButtonPushed, true);
            app.OzonogramaButton.Text = 'Ozonograma';
            app.HTML2 = uihtml(app.OzonogramaTab);
            app.HTML2.HTMLSource = 'html/logo.html';
            app.CrearnuevoLabel2 = uilabel(app.OzonogramaTab);
            app.CrearnuevoLabel2.Text = 'Crear nuevo:';
            app.EspectroUVTab = uitab(app.TabGroup);
            app.EspectroUVTab.Title = 'Espectro UV-Vis';
            app.TabGroupEspectro = uitabgroup(app.EspectroUVTab);
            app.TabGroupEspectro.Visible = 'off';
            app.EspectroTab = uitab(app.TabGroupEspectro);
            app.EspectroTab.Title = 'Espectro UV-Vis';
            app.EspectroButton = uibutton(app.EspectroUVTab, 'push');
            app.EspectroButton.ButtonPushedFcn = createCallbackFcn(app, @EspectroButtonPushed, true);
            app.EspectroButton.Text = 'Espectro UV-Vis';
            app.HTML3 = uihtml(app.EspectroUVTab);
            app.HTML3.HTMLSource = 'html/logo.html';
            app.CrearnuevoLabel3 = uilabel(app.EspectroUVTab);
            app.CrearnuevoLabel3.Text = 'Crear nuevo:';
            app.BiorreaccionTab = uitab(app.TabGroup);
            app.BiorreaccionTab.Title = 'Biorreacción';
            app.TabGroupBiorreaccion = uitabgroup(app.BiorreaccionTab);
            app.TabGroupBiorreaccion.Visible = 'off';
            app.BioreaccionTab = uitab(app.TabGroupBiorreaccion);
            app.BioreaccionTab.Title = 'Biorreacción';
            app.BiorreaccionButton = uibutton(app.BiorreaccionTab, 'push');
            app.BiorreaccionButton.ButtonPushedFcn = createCallbackFcn(app, @BiorreaccionButtonPushed, true);
            app.BiorreaccionButton.Text = 'Biorreacción';
            app.HTML4 = uihtml(app.BiorreaccionTab);
            app.HTML4.HTMLSource = 'html/logo.html';
            app.CrearnuevoLabel4 = uilabel(app.BiorreaccionTab);
            app.CrearnuevoLabel4.Text = 'Crear nuevo:';
            app.ReporteTab = uitab(app.TabGroup);
            app.ReporteTab.Title = 'Reporte';
            app.TabGroupReporte = uitabgroup(app.ReporteTab);
            app.TabGroupReporte.Visible = 'off';
            app.ReporteTab5 = uitab(app.TabGroupReporte);
            app.ReporteTab5.Title = 'Reporte';
            app.ReporteButton = uibutton(app.ReporteTab, 'push');
            app.ReporteButton.ButtonPushedFcn = createCallbackFcn(app, @ReporteButtonPushed, true);
            app.ReporteButton.Text = 'Reporte';
            app.HTML5 = uihtml(app.ReporteTab);
            app.HTML5.HTMLSource = 'html/logo.html';
            app.CrearnuevoLabel5 = uilabel(app.ReporteTab);
            app.CrearnuevoLabel5.Text = 'Crear nuevo:';
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.HTML7 = uihtml(app.InstruccionesTab);
            app.HTML7.HTMLSource = 'html/logo.html';
            app.HTML6 = uihtml(app.InstruccionesTab);
            app.HTML6.HTMLSource = 'html/instructions.html';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
            app.HTML9 = uihtml(app.AcercadeTab);
            app.HTML9.HTMLSource = 'html/left.html';
            app.HTML8 = uihtml(app.AcercadeTab);
            app.HTML8.HTMLSource = 'html/logo.html';
            app.HTML9_2 = uihtml(app.AcercadeTab);
            app.HTML9_2.HTMLSource = 'html/right.html';
            app.GitHubButton = uibutton(app.AcercadeTab, 'push');
            app.GitHubButton.ButtonPushedFcn = createCallbackFcn(app, @GitHubButtonPushed, true);
            app.GitHubButton.Icon = 'github-button.svg';
            app.GitHubButton.Text = 'GitHub';
            app.HTML9_3 = uihtml(app.AcercadeTab);
            app.HTML9_3.HTMLSource = 'html/version.html';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laiiqautilities_win64

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end